classdef war__model < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure         matlab.ui.Figure
        RightPanel       matlab.ui.container.Panel
        GridLayout3      matlab.ui.container.GridLayout
        B_area           matlab.ui.control.Spinner
        Spinner_13Label  matlab.ui.control.Label
        B_gue_rate       matlab.ui.control.Slider
        Slider_2Label    matlab.ui.control.Label
        B_add_rate       matlab.ui.control.Spinner
        Spinner_12Label  matlab.ui.control.Label
        B_reduce_rate    matlab.ui.control.Spinner
        Spinner_11Label  matlab.ui.control.Label
        B_hit_rate       matlab.ui.control.Spinner
        Spinner_10Label  matlab.ui.control.Label
        B_shoot_rate     matlab.ui.control.Spinner
        Spinner_9Label   matlab.ui.control.Label
        B_initial_num    matlab.ui.control.Spinner
        Spinner_8Label   matlab.ui.control.Label
        Label_10         matlab.ui.control.Label
        CenterPanel      matlab.ui.container.Panel
        GridLayout4      matlab.ui.container.GridLayout
        Button_5         matlab.ui.control.Button
        Button_4         matlab.ui.control.Button
        winner_show      matlab.ui.control.Label
        A_Lamp           matlab.ui.control.Lamp
        B_Lamp           matlab.ui.control.Lamp
        Button_3         matlab.ui.control.Button
        Label_11         matlab.ui.control.Label
        Button_2         matlab.ui.control.Button
        Button           matlab.ui.control.Button
        wartime          matlab.ui.control.Slider
        UIAxes           matlab.ui.control.UIAxes
        LeftPanel        matlab.ui.container.Panel
        GridLayout2      matlab.ui.container.GridLayout
        A_gue_rate       matlab.ui.control.Slider
        Label_9          matlab.ui.control.Label
        A_area           matlab.ui.control.Spinner
        Label_8          matlab.ui.control.Label
        A_add_rate       matlab.ui.control.Spinner
        Label_6          matlab.ui.control.Label
        A_reduce_rate    matlab.ui.control.Spinner
        Label_5          matlab.ui.control.Label
        A_hit_rate       matlab.ui.control.Spinner
        Label_4          matlab.ui.control.Label
        A_shoot_rate     matlab.ui.control.Spinner
        Label_3          matlab.ui.control.Label
        A_initial_num    matlab.ui.control.Spinner
        Label_2          matlab.ui.control.Label
        Label            matlab.ui.control.Label
    end

    properties (Access = private)
        t;
        y;
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.UIFigure.Name = '《战争模型》自动化2203班 胡耀文 U202215135';
            app.UIFigure.Position = [100, 100, 1000, 580];
        end

        % Button pushed function: Button
        function ButtonPushed2(app, event)
            [app.t,app.y]=calculate(app);
        end

        % Button pushed function: Button_2
        function Button_2Pushed2(app, event)
            set_rand_data(app);
        end

        % Button pushed function: Button_3
        function Button_3Pushed2(app, event)
            set_sta_data(app);
        end

        % Button pushed function: Button_4
        function Button_4Pushed(app, event)
            draw_point(app,app.t,app.y);
        end

        % Button pushed function: Button_5
        function Button_5Pushed(app, event)
            draw_bar(app,app.t,app.y);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.LeftPanel = uipanel(app.UIFigure);
            app.GridLayout2 = uigridlayout(app.LeftPanel);
            app.Label = uilabel(app.GridLayout2);
            app.Label.Text = '甲军';
            app.Label_2 = uilabel(app.GridLayout2);
            app.Label_2.Text = '初始兵力';
            app.A_initial_num = uispinner(app.GridLayout2);
            app.A_initial_num.Step = 100;
            app.A_initial_num.Limits = [0 100000];
            app.A_initial_num.Value = 6000;
            app.Label_3 = uilabel(app.GridLayout2);
            app.Label_3.Text = '射击率';
            app.A_shoot_rate = uispinner(app.GridLayout2);
            app.A_shoot_rate.Step = 0.05;
            app.A_shoot_rate.Limits = [0 1];
            app.A_shoot_rate.Value = 0.6;
            app.Label_4 = uilabel(app.GridLayout2);
            app.Label_4.Text = '命中率';
            app.A_hit_rate = uispinner(app.GridLayout2);
            app.A_hit_rate.Step = 0.05;
            app.A_hit_rate.Limits = [0 1];
            app.A_hit_rate.Value = 0.8;
            app.Label_5 = uilabel(app.GridLayout2);
            app.Label_5.Text = '非战斗减员率';
            app.A_reduce_rate = uispinner(app.GridLayout2);
            app.A_reduce_rate.Step = 0.03;
            app.A_reduce_rate.Limits = [0 1];
            app.A_reduce_rate.Value = 0.025;
            app.Label_6 = uilabel(app.GridLayout2);
            app.Label_6.Text = '增援率';
            app.A_add_rate = uispinner(app.GridLayout2);
            app.A_add_rate.Step = 0.03;
            app.A_add_rate.Limits = [0 1];
            app.A_add_rate.Value = 0.2;
            app.Label_8 = uilabel(app.GridLayout2);
            app.Label_8.Text = '有效活动面积';
            app.A_area = uispinner(app.GridLayout2);
            app.A_area.Step = 10;
            app.A_area.Limits = [0 100];
            app.A_area.Value = 20;
            app.Label_9 = uilabel(app.GridLayout2);
            app.Label_9.Text = '参与游击战的比例';
            app.A_gue_rate = uislider(app.GridLayout2);
            app.A_gue_rate.Limits = [0 1];
            app.A_gue_rate.MajorTicks = [0 0.2 0.4 0.6 0.8 1];
            app.A_gue_rate.Value = 0.7;
            app.CenterPanel = uipanel(app.UIFigure);
            app.GridLayout4 = uigridlayout(app.CenterPanel);
            app.UIAxes = uiaxes(app.GridLayout4);
            title(app.UIAxes, '战争模型')
            xlabel(app.UIAxes, '战争持续时间')
            ylabel(app.UIAxes, '两军剩余人数')
            colormap(app.UIAxes, 'jet')
            app.wartime = uislider(app.GridLayout4);
            app.wartime.Value = 20;
            app.Button = uibutton(app.GridLayout4, 'push');
            app.Button.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushed2, true);
            app.Button.Text = '开始仿真';
            app.Button_2 = uibutton(app.GridLayout4, 'push');
            app.Button_2.ButtonPushedFcn = createCallbackFcn(app, @Button_2Pushed2, true);
            app.Button_2.Text = '随机预设参数';
            app.Label_11 = uilabel(app.GridLayout4);
            app.Label_11.Text = '战争持续时间';
            app.Button_3 = uibutton(app.GridLayout4, 'push');
            app.Button_3.ButtonPushedFcn = createCallbackFcn(app, @Button_3Pushed2, true);
            app.Button_3.Text = '固定预设参数';
            app.B_Lamp = uilamp(app.GridLayout4);
            app.A_Lamp = uilamp(app.GridLayout4);
            app.winner_show = uilabel(app.GridLayout4);
            app.winner_show.Text = '';
            app.Button_4 = uibutton(app.GridLayout4, 'push');
            app.Button_4.ButtonPushedFcn = createCallbackFcn(app, @Button_4Pushed, true);
            app.Button_4.Text = '点图仿真';
            app.Button_5 = uibutton(app.GridLayout4, 'push');
            app.Button_5.ButtonPushedFcn = createCallbackFcn(app, @Button_5Pushed, true);
            app.Button_5.Text = '柱状图仿真';
            app.RightPanel = uipanel(app.UIFigure);
            app.GridLayout3 = uigridlayout(app.RightPanel);
            app.Label_10 = uilabel(app.GridLayout3);
            app.Label_10.Text = '乙军';
            app.Spinner_8Label = uilabel(app.GridLayout3);
            app.Spinner_8Label.Text = '初始兵力';
            app.B_initial_num = uispinner(app.GridLayout3);
            app.B_initial_num.Step = 100;
            app.B_initial_num.Limits = [0 100000];
            app.B_initial_num.Value = 8000;
            app.Spinner_9Label = uilabel(app.GridLayout3);
            app.Spinner_9Label.Text = '射击率';
            app.B_shoot_rate = uispinner(app.GridLayout3);
            app.B_shoot_rate.Step = 0.05;
            app.B_shoot_rate.Limits = [0 1];
            app.B_shoot_rate.Value = 0.7;
            app.Spinner_10Label = uilabel(app.GridLayout3);
            app.Spinner_10Label.Text = '命中率';
            app.B_hit_rate = uispinner(app.GridLayout3);
            app.B_hit_rate.Step = 0.05;
            app.B_hit_rate.Limits = [0 1];
            app.B_hit_rate.Value = 0.5;
            app.Spinner_11Label = uilabel(app.GridLayout3);
            app.Spinner_11Label.Text = '非战斗减员率';
            app.B_reduce_rate = uispinner(app.GridLayout3);
            app.B_reduce_rate.Step = 0.03;
            app.B_reduce_rate.Limits = [0 1];
            app.B_reduce_rate.Value = 0.38;
            app.Spinner_12Label = uilabel(app.GridLayout3);
            app.Spinner_12Label.Text = '增援率';
            app.B_add_rate = uispinner(app.GridLayout3);
            app.B_add_rate.Step = 0.03;
            app.B_add_rate.Limits = [0 1];
            app.B_add_rate.Value = 0.05;
            app.Slider_2Label = uilabel(app.GridLayout3);
            app.Slider_2Label.Text = '参与游击战的比例';
            app.B_gue_rate = uislider(app.GridLayout3);
            app.B_gue_rate.Limits = [0 1];
            app.B_gue_rate.Value = 0.2;
            app.Spinner_13Label = uilabel(app.GridLayout3);
            app.Spinner_13Label.Text = '有效活动面积';
            app.B_area = uispinner(app.GridLayout3);
            app.B_area.Step = 10;
            app.B_area.Limits = [0 100];
            app.B_area.Value = 35;
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = war__model

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end